classdef DesignAeronef < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DesigndaronefUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ParamtredemissionTab            matlab.ui.container.Tab
        WPayloadlbLabel                 matlab.ui.control.Label
        WPayload                        matlab.ui.control.NumericEditField
        CalculerPayload                 matlab.ui.control.Button
        PAXEditFieldLabel               matlab.ui.control.Label
        PAX                             matlab.ui.control.NumericEditField
        CrewEditFieldLabel              matlab.ui.control.Label
        Crew                            matlab.ui.control.NumericEditField
        WbaglbLabel                     matlab.ui.control.Label
        Wbag                            matlab.ui.control.NumericEditField
        WpeoplelbLabel                  matlab.ui.control.Label
        Wpeople                         matlab.ui.control.NumericEditField
        RangeNMLabel                    matlab.ui.control.Label
        Range                           matlab.ui.control.NumericEditField
        ChoixDesignerGnralTab           matlab.ui.container.Tab
        MachdeCroisireEditFieldLabel    matlab.ui.control.Label
        M_cruise                        matlab.ui.control.NumericEditField
        AltitudedeCroisireftEditFieldLabel  matlab.ui.control.Label
        H_cruise                        matlab.ui.control.NumericEditField
        AllongementAREditFieldLabel     matlab.ui.control.Label
        AR                              matlab.ui.control.NumericEditField
        Chargelairedbutdecroisirelbft2EditFieldLabel  matlab.ui.control.Label
        WS_croisiere                    matlab.ui.control.NumericEditField
        PoussetotaleEditFieldLabel      matlab.ui.control.Label
        Thrust_tot                      matlab.ui.control.NumericEditField
        TSCFEditFieldLabel              matlab.ui.control.Label
        TSFC                            matlab.ui.control.NumericEditField
        TxdeMonteEditFieldLabel         matlab.ui.control.Label
        tx_climb                        matlab.ui.control.NumericEditField
        CD0EditFieldLabel               matlab.ui.control.Label
        CD0                             matlab.ui.control.NumericEditField
        TempdattenteminEditFieldLabel   matlab.ui.control.Label
        T_loiter                        matlab.ui.control.NumericEditField
        FractiondessenceavantatterissageEditFieldLabel  matlab.ui.control.Label
        Ffuel_res                       matlab.ui.control.NumericEditField
        FractiondessencedanslesconduitesEditFieldLabel  matlab.ui.control.Label
        Ffuel_trap                      matlab.ui.control.NumericEditField
        RecalculerDesign                matlab.ui.control.Button
        DistancedatterisageLabel        matlab.ui.control.Label
        Dlanding                        matlab.ui.control.NumericEditField
        ChoixDesignAileTab              matlab.ui.container.Tab
        AngledeflcheLeradEditFieldLabel  matlab.ui.control.Label
        Angle_fleche_Le                 matlab.ui.control.NumericEditField
        EffilementLabel                 matlab.ui.control.Label
        Effilement                      matlab.ui.control.NumericEditField
        QLabel                          matlab.ui.control.Label
        Q                               matlab.ui.control.NumericEditField
        ProfildelaileEditFieldLabel     matlab.ui.control.Label
        PolaireAile                     matlab.ui.control.EditField
        tcmaxLabel                      matlab.ui.control.Label
        epmax                           matlab.ui.control.NumericEditField
        xcmaxLabel                      matlab.ui.control.Label
        xcm                             matlab.ui.control.NumericEditField
        AngledeflchetcmaxradLabel       matlab.ui.control.Label
        Angle_fleche_epmax              matlab.ui.control.NumericEditField
        ChoixDesignFuselageTab          matlab.ui.container.Tab
        LongueurdufuselageftEditFieldLabel  matlab.ui.control.Label
        l                               matlab.ui.control.NumericEditField
        GrandaxeEllipseftEditFieldLabel  matlab.ui.control.Label
        Aside                           matlab.ui.control.NumericEditField
        PetitaxeEllipseftEditFieldLabel  matlab.ui.control.Label
        Atop                            matlab.ui.control.NumericEditField
        kEtatdesurfaceEditFieldLabel    matlab.ui.control.Label
        k_surface                       matlab.ui.control.NumericEditField
        QfuselageLabel                  matlab.ui.control.Label
        Q_fuselage                      matlab.ui.control.NumericEditField
        TabGroup2                       matlab.ui.container.TabGroup
        PoidsTab                        matlab.ui.container.Tab
        PoidsVideLabel                  matlab.ui.control.Label
        Wempty                          matlab.ui.control.NumericEditField
        PoidsdessenceLabel              matlab.ui.control.Label
        Wfuel                           matlab.ui.control.NumericEditField
        PoidsaudcollageLabel            matlab.ui.control.Label
        Wto                             matlab.ui.control.NumericEditField
        PoidslatterisageLabel           matlab.ui.control.Label
        Wlanding                        matlab.ui.control.NumericEditField
        ChargelairTab                   matlab.ui.container.Tab
        WSdcollageEditFieldLabel        matlab.ui.control.Label
        WS_TO                           matlab.ui.control.NumericEditField
        WSMonteEditFieldLabel           matlab.ui.control.Label
        WS_climb                        matlab.ui.control.NumericEditField
        WSCroissireEditFieldLabel       matlab.ui.control.Label
        WS_cruise                       matlab.ui.control.NumericEditField
        WSLandingEditFieldLabel         matlab.ui.control.Label
        WS_landing                      matlab.ui.control.NumericEditField
        PousseTab                       matlab.ui.container.Tab
        PoussencessaireaudcollageEditFieldLabel  matlab.ui.control.Label
        T_to                            matlab.ui.control.NumericEditField
        TWaudcollageLabel               matlab.ui.control.Label
        TW_to                           matlab.ui.control.NumericEditField
        AileTab                         matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        GometrieTab                     matlab.ui.container.Tab
        Surfaceft2EditFieldLabel        matlab.ui.control.Label
        S                               matlab.ui.control.NumericEditField
        EnvergureftLabel                matlab.ui.control.Label
        b                               matlab.ui.control.NumericEditField
        CrftLabel                       matlab.ui.control.Label
        Cr                              matlab.ui.control.NumericEditField
        CtftLabel                       matlab.ui.control.Label
        Ct                              matlab.ui.control.NumericEditField
        CmftLabel                       matlab.ui.control.Label
        Cmoy                            matlab.ui.control.NumericEditField
        AngledeflcheC4radLabel          matlab.ui.control.Label
        Angle_fleche_c4                 matlab.ui.control.NumericEditField
        AngledeflcheC2radLabel          matlab.ui.control.Label
        Angle_fleche_c2                 matlab.ui.control.NumericEditField
        AerodynamismeTab                matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        AngledeCalageradEditFieldLabel  matlab.ui.control.Label
        angle_calage_aile               matlab.ui.control.NumericEditField
        acoeffdiraileEditFieldLabel     matlab.ui.control.Label
        a_aile                          matlab.ui.control.NumericEditField
        a0coeffdirprofilEditFieldLabel  matlab.ui.control.Label
        a0_aile                         matlab.ui.control.NumericEditField
        CLdecroisireLabel               matlab.ui.control.Label
        CL_cruise                       matlab.ui.control.NumericEditField
        ReynoldsLabel                   matlab.ui.control.Label
        Re_aile                         matlab.ui.control.NumericEditField
        CD0Label                        matlab.ui.control.Label
        CD0_aile                        matlab.ui.control.NumericEditField
        FuselageTab                     matlab.ui.container.Tab
        ReynoldsEditFieldLabel          matlab.ui.control.Label
        Re_fuslage                      matlab.ui.control.NumericEditField
        CD0Label_2                      matlab.ui.control.Label
        CD0_fuslage                     matlab.ui.control.NumericEditField
        EmpenageTab                     matlab.ui.container.Tab
        PerformanceTab                  matlab.ui.container.Tab
        FinessemaxLabel                 matlab.ui.control.Label
        Finesse                         matlab.ui.control.NumericEditField
        MachRelLabel                    matlab.ui.control.Label
        M_real                          matlab.ui.control.NumericEditField
        AngledeMontradLabel             matlab.ui.control.Label
        angle_climb                     matlab.ui.control.NumericEditField
        CLmaxLandingLabel               matlab.ui.control.Label
        CL_max_landing                  matlab.ui.control.NumericEditField
        CLmaxTakeoffLabel               matlab.ui.control.Label
        CL_max_TO                       matlab.ui.control.NumericEditField
        DistancedeDcollageftLabel       matlab.ui.control.Label
        DTO                             matlab.ui.control.NumericEditField
        VitessededcrochageftsLabel      matlab.ui.control.Label
        Vstall                          matlab.ui.control.NumericEditField
        VitessededcollageftsLabel       matlab.ui.control.Label
        VTo                             matlab.ui.control.NumericEditField
        TempratureKLabel                matlab.ui.control.Label
        T                               matlab.ui.control.NumericEditField
        VitessedeCroisireftsLabel       matlab.ui.control.Label
        V_cruise                        matlab.ui.control.NumericEditField
        RsultatsdeCalculLabel           matlab.ui.control.Label
        ParamtresUtilisateurLabel       matlab.ui.control.Label
    end

    methods (Static)
        function [a0,alpha0,incidence,Cl,test] = PortanceProfil(Pol)
            [~,i_fin_max] = max(Pol.data(:,2)./Pol.data(:,3));
            incidence=Pol.data(:,1);
            incidencefmax=(incidence(i_fin_max));
            Cl=Pol.data(:,2);
            
            test = logical(incidence>-10).*logical(incidence<5);
            
            [xData, yData] = prepareCurveData( incidence(logical(test))*pi/180, Cl(logical(test)));
            incidence=incidence*pi/180;
            % Set up fittype and options.
            ft = fittype( 'poly1' );
            opts = fitoptions( 'Method', 'LinearLeastSquares');
            
            % Fit model to data.
            fitresult = fit( xData, yData, ft, opts );
            p = coeffvalues(fitresult);
            a0 = p(1);
            alpha0 = -p(2)/p(1); %rad
        end
        function mu = viscosity(T)
            mu = ((8.8848e-15*(T^3)-3.2398e-11*(T^2)+6.2657e-8*T+2.3543e-6)/9.81)*(1/3.28)^2*2.2048;
        end
    end
    methods (Access = private)
    
        function [Vmoy,Vmach]=poids(app)
            global rho;
            fclimb = 1 -0.04*app.M_cruise.Value;
            fdec = 0.975;
            k=1/(pi()*app.AR.Value*0.8);
            [ app.Wto.Value, app.Wfuel.Value, app.Wempty.Value ] = itertow('jet-transport',app.M_cruise.Value, app.H_cruise.Value, app.AR.Value, app.TSFC.Value, app.T_loiter.Value, app.Ffuel_res.Value, app.Ffuel_trap.Value, app.WPayload.Value, app.CD0.Value,app.Range.Value);
        
           app.Wlanding.Value = app.Wempty.Value + app.Wfuel.Value*(app.Ffuel_res.Value+app.Ffuel_trap.Value)+app.WPayload.Value;
           %Wmoy = (Wlanding+Wto)/2;
           Wmoy = app.Wto.Value*fdec*fclimb;
           
           app.S.Value=Wmoy/app.WS_croisiere.Value;                       %Charge à l'aire en milieu de mission
           [T, a, P, rho] = atmosisa(app.H_cruise.Value/3.28);    %masse volumique au Hcruise
           rho = rho*0.06854/3.28^3;                    %(slug/ft^3)
           
           Vmoy = sqrt(2/rho*Wmoy/app.S.Value)*(3*k/app.CD0.Value)^0.25;    %vitesse moyenne lors du vol (ft/s)
           Vmach=app.M_cruise.Value*sqrt(1.4*1716.5*T*9/5);       %vitesse désirée pour le Mach du choix de designer (ft/s)
           app.M_real.Value = Vmoy/sqrt(1.4*1716.5*T*9/5);
        end
        
        function ratio = performances(app)
            global k;
            
            app.WS_landing.Value = app.Wlanding.Value/app.S.Value; % Charge à l'aire à l'attérissage
            app.WS_TO.Value = app.Wto.Value/app.S.Value; %Charge à l'aire au décollage
           
            app.CL_max_landing.Value = app.WS_landing.Value / ((app.Dlanding.Value - 400) / 118);  % Calcul du CLmax,L = CLmax
            
            % Calcul du CLmax,TO
            app.CL_max_TO.Value = 0.8 * app.CL_max_landing.Value;
            
            q = .5*density(0)*app.VTo.Value^2;
            app.T_to.Value = q*app.S.Value*(app.CD0.Value+k*app.CL_max_TO.Value^2)+app.Wto.Value*sin(app.angle_climb.Value);
            app.TW_to.Value = app.T_to.Value/app.Wto.Value;
            WS_climb_int = (app.TW_to.Value - sin(app.angle_climb.Value))^2-4*app.CD0.Value*cos(app.angle_climb.Value)^2*k;
            app.WS_climb.Value = q/(2*cos(app.angle_climb.Value)^2*k)*((app.TW_to.Value - sin(app.angle_climb.Value)+sqrt(WS_climb_int)));
            
            % Calcul de la distance de decollage
            app.TW_to.Value = app.Thrust_tot.Value/app.Wto.Value;
            app.DTO.Value = 20.9*(app.WS_TO.Value * (1/app.CL_max_TO.Value) * (1/app.TW_to.Value)) + 87*sqrt((app.WS_TO.Value * (1/app.CL_max_TO.Value) * (1/app.TW_to.Value))*app.TW_to.Value);
            
            % Calcul de la vitesse de decrochage
            app.Vstall.Value = sqrt((2*app.Wto.Value)/(density(0)*app.S.Value*app.CL_max_landing.Value));
            
            % Calcul de la vitesse de decollage
            app.VTo.Value = 1.1*app.Vstall.Value;
            
            ratio=(app.Thrust_tot.Value/(2*app.Wto.Value))/(sin(app.angle_climb.Value)+sqrt(app.CD0.Value*k));% verif puissance avec panne de 1 moteur
            
            fcruise = 1 / exp(app.Range.Value*app.TSFC.Value*6080/(vitesse(app.M_cruise.Value,app.H_cruise.Value)*app.Finesse.Value*3600));
            fl=1/exp(app.T_loiter.Value*app.TSFC.Value/(app.Finesse.Value*60));
            fclimb = 1 -0.04*app.M_cruise.Value;
            fdec = 0.975;
            
            app.WS_climb.Value = app.WS_climb.Value/(fdec);
            app.WS_cruise.Value = app.WS_croisiere.Value/(fdec*fclimb);
            app.WS_landing.Value = app.WS_landing.Value/(fdec*fclimb*fcruise*fl);
        end
       
        
        function PortanceAile(app)
            global rho;
            Pol = importdata(app.PolaireAile.Value);
            [a0,alpha0,incidence,Cl,test] = DesignAeronef.PortanceProfil(Pol);
            
            app.T.Value = tempatmstd(app.H_cruise.Value);
            app.b.Value=sqrt(app.S.Value*app.AR.Value);        %envergure
            app.Cr.Value = 2*app.b.Value/(app.AR.Value*(1+app.Effilement.Value));    %corde à la base de l'aile
            app.Ct.Value = app.Effilement.Value*app.Cr.Value;             %corde en bout d'aile
            app.Cmoy.Value = 2*app.Cr.Value/3*(1+app.Effilement.Value+app.Effilement.Value^2)/(1+app.Effilement.Value);
            
            app.Angle_fleche_c4.Value = atan(tan(app.Angle_fleche_Le.Value)-app.Cr.Value/(2*app.b.Value)*(1-app.Effilement.Value));  %angle de flèche au quart de la corde (rad)
            app.Angle_fleche_c2.Value = atan(tan(app.Angle_fleche_Le.Value)-app.Cr.Value/app.b.Value*(1-app.Effilement.Value));  %angle de flèche au mileu de la corde (rad)
            
            a = a0*cos(app.Angle_fleche_c2.Value)/(sqrt(1+(a0*cos(app.Angle_fleche_c2.Value)/(pi()*app.AR.Value))^2)+a0*cos(app.Angle_fleche_c2.Value)/(pi()*app.AR.Value));
            
            app.a_aile.Value = a;
            app.a0_aile.Value = a0;
            
            Cl3D=a*(incidence(logical(test))-alpha0);
            Cl2D=a0*(incidence(logical(test))-alpha0);
            app.CL_cruise.Value = (app.WS_croisiere.Value*app.S.Value)/(0.5*rho*app.V_cruise.Value^2*app.S.Value);
            
            if app.CL_cruise.Value<max(Cl3D)
                app.angle_calage_aile.Value = alpha0+ app.CL_cruise.Value/a;
            else
                app.angle_calage_aile.Value = inf;
            end
            
            %alpha_aile_croisiere=interp1(Cl3D,incidence,CL_Croisiere);
            
            plot(app.UIAxes,incidence(logical(test)).*180/pi, Cl3D,'-',incidence(logical(test)).*180/pi, Cl2D,'--',incidence.*180/pi,Cl,'k:');
            legend(app.UIAxes,'CL 3D','CL 2D','Profil 4412','location','NorthWest');
        end
        
        function TraineeAile(app)
            global rho;
            app.Re_aile.Value = rho*app.Cmoy.Value*app.V_cruise.Value/DesignAeronef.viscosity(app.T.Value);
            
            Cf = 0.455/(log(app.Re_aile.Value)^2.58*(1+0.144*(app.M_real.Value*cos(app.Angle_fleche_Le.Value))^2)^0.65);   %Coefficient de friction d'une plaque plane
            
            FF =(1+0.6/app.xcm.Value*(app.epmax.Value)+100*app.epmax.Value^4)*(1.34*app.M_cruise.Value^0.18*(cos(app.Angle_fleche_epmax.Value))^0.28);
            
            Swet = app.S.Value*(1.977+0.52*(app.epmax.Value));   %On va tracer l'aile en fonction du profil choisi et aller mesurer ça dans Solidworks
            
            app.CD0_aile.Value = Cf*FF*app.Q.Value*Swet/app.S.Value;
            
        end
        
        
        function Fuslage(app)
            global rho;
            d=min([app.Aside.Value app.Atop.Value]);     %choix designer

            Re_eff = 38.21*(app.l.Value/app.k_surface.Value)^1.053; % Reynolds effectif / rugosite (M<1)
            app.Re_fuslage.Value = min([rho*app.l.Value*app.V_cruise.Value/DesignAeronef.viscosity(app.T.Value) Re_eff]);
            Cf = 0.455/(log(app.Re_fuslage.Value)^2.58*(1+0.144*(app.M_real.Value)^2)^0.65);
            
            FF_fuselage = 1+60/(app.l.Value/d)^3+app.l.Value/(400*d);
            Swet_fuselage = pi*((app.Aside.Value+app.Atop.Value)/2)*app.l.Value;
            app.CD0_fuslage.Value=Cf*FF_fuselage*app.Q_fuselage.Value*Swet_fuselage/(app.Atop.Value*app.l.Value);
        end
        
        function Recalculate(app)
            performances(app);
            PortanceAile(app);
            TraineeAile(app);
            Fuslage(app);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Projet;
            global A CD0 k  Charge_Ailaire SL Crew  Fuel_trap  H_cruise Fuel_res  M_cruise   PAX  Range T_loiter Thrust_tot  TSFC  tx_montee  Wbag  Wpeople;
            global EffAile AngflecheLe Q_aile;
            
            app.PAX.Value = PAX;
            app.Crew.Value = Crew;
            app.Wbag.Value = Wbag;
            app.Wpeople.Value = Wpeople;
            app.Range.Value = Range;
            
            app.M_cruise.Value = M_cruise;
            app.H_cruise.Value = H_cruise;
            app.AR.Value = A;
            app.WS_croisiere.Value = Charge_Ailaire;
            app.Thrust_tot.Value = Thrust_tot;
            app.TSFC.Value = TSFC;
            app.tx_climb.Value = tx_montee;
            app.CD0.Value=CD0;
            app.T_loiter.Value=T_loiter;
            app.Ffuel_res.Value=Fuel_res;
            app.Ffuel_trap.Value=Fuel_trap;
            
            app.Dlanding.Value= SL;
            app.Q.Value = Q_aile;
            app.Angle_fleche_Le.Value = AngflecheLe;
            app.Effilement.Value = EffAile;
            
            global Angle_fleche_epmax xcm epmax;
            
            app.Angle_fleche_epmax.Value=Angle_fleche_epmax;
            app.epmax.Value = epmax;
            app.xcm.Value = xcm;
            
            global Atop Aside l k_surface Q_fuselage;
            
            app.Atop.Value= Atop;
            app.Aside.Value=Aside;
            app.l.Value = l;
            app.k_surface.Value=k_surface;
            app.Q_fuselage.Value = Q_fuselage;
            
            k=1/(pi()*app.AR.Value*0.8);
            app.CalculerPayloadPushed();
            app.RecalculerDesignButtonPushed();
            Recalculate(app);
            
        end

        % Callback function: CalculerPayload, Crew, PAX, Range, Wbag, 
        % Wpeople
        function CalculerPayloadPushed(app, event)

            
            app.WPayload.Value = app.PAX.Value*(app.Wpeople.Value+app.Wbag.Value)+app.Crew.Value*(app.Wpeople.Value+10);

            [Vmoy,Vmach] = poids(app);
               
            while Vmoy/Vmach > 1.02;                         %While qui décroit l'altitude moyenne pour rapprocher la vitesse moyenne du vol au mach de designer
                app.H_cruise.Value = app.H_cruise.Value - 20;
               [Vmoy,Vmach] = poids(app);
            end
            app.V_cruise.Value = Vmoy;
            Recalculate(app);
        end

        % Callback function: AR, CD0, Ffuel_res, Ffuel_trap, 
        % H_cruise, M_cruise, RecalculerDesign, TSFC, T_loiter, 
        % Thrust_tot, WS_croisiere, tx_climb
        function RecalculerDesignButtonPushed(app, event)
            global k
            app.angle_climb.Value= atan(app.tx_climb.Value);

            f = 1/sqrt(4*k*app.CD0.Value);
            app.Finesse.Value = f;
            app.CalculerPayloadPushed();
           Recalculate(app);
        end

        % Callback function: Angle_fleche_Le, Angle_fleche_epmax, 
        % Effilement, PolaireAile, Q, epmax, xcm
        function AileDesignChanged(app, event)
           Recalculate(app);
        end

        % Callback function: Aside, Atop, Q_fuselage, k_surface, l
        function FuselageDesignChanged(app, event)
            Recalculate(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DesigndaronefUIFigure = uifigure;
            app.DesigndaronefUIFigure.Name = 'Design d''aéronef';
            app.TabGroup = uitabgroup(app.DesigndaronefUIFigure);
            app.ParamtredemissionTab = uitab(app.TabGroup);
            app.ParamtredemissionTab.Title = 'Paramètre de mission';
            app.WPayloadlbLabel = uilabel(app.ParamtredemissionTab);
            app.WPayloadlbLabel.Text = 'WPayload [lb]';
            app.WPayload = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.WPayload.ValueDisplayFormat = '%.2f';
            app.WPayload.Editable = 'off';
            app.CalculerPayload = uibutton(app.ParamtredemissionTab, 'push');
            app.CalculerPayload.ButtonPushedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.CalculerPayload.Text = 'Calculer Payload';
            app.PAXEditFieldLabel = uilabel(app.ParamtredemissionTab);
            app.PAXEditFieldLabel.Text = 'PAX';
            app.PAX = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.PAX.ValueDisplayFormat = '%.0f';
            app.PAX.ValueChangedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.CrewEditFieldLabel = uilabel(app.ParamtredemissionTab);
            app.CrewEditFieldLabel.Text = 'Crew';
            app.Crew = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.Crew.ValueDisplayFormat = '%.0f';
            app.Crew.ValueChangedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.WbaglbLabel = uilabel(app.ParamtredemissionTab);
            app.WbaglbLabel.Text = 'Wbag [lb]';
            app.Wbag = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.Wbag.ValueDisplayFormat = '%.0f';
            app.Wbag.ValueChangedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.WpeoplelbLabel = uilabel(app.ParamtredemissionTab);
            app.WpeoplelbLabel.Text = 'Wpeople [lb]';
            app.Wpeople = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.Wpeople.ValueDisplayFormat = '%.0f';
            app.Wpeople.ValueChangedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.RangeNMLabel = uilabel(app.ParamtredemissionTab);
            app.RangeNMLabel.Text = 'Range [NM]';
            app.Range = uieditfield(app.ParamtredemissionTab, 'numeric');
            app.Range.ValueDisplayFormat = '%.0f';
            app.Range.ValueChangedFcn = createCallbackFcn(app, @CalculerPayloadPushed, true);
            app.ChoixDesignerGnralTab = uitab(app.TabGroup);
            app.ChoixDesignerGnralTab.Title = 'Choix Designer - Général';
            app.MachdeCroisireEditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.MachdeCroisireEditFieldLabel.Text = 'Mach de Croisière';
            app.M_cruise = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.M_cruise.ValueDisplayFormat = '%.3f';
            app.M_cruise.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.AltitudedeCroisireftEditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.AltitudedeCroisireftEditFieldLabel.Text = 'Altitude de Croisière (ft)';
            app.H_cruise = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.H_cruise.ValueDisplayFormat = '%.2f';
            app.H_cruise.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.AllongementAREditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.AllongementAREditFieldLabel.Text = 'Allongement (AR)';
            app.AR = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.AR.ValueDisplayFormat = '%.2f';
            app.AR.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.Chargelairedbutdecroisirelbft2EditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.Chargelairedbutdecroisirelbft2EditFieldLabel.Text = 'Charge à l''aire(début de croisière) [lb/ft^2]';
            app.WS_croisiere = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.WS_croisiere.ValueDisplayFormat = '%.2f';
            app.WS_croisiere.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.PoussetotaleEditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.PoussetotaleEditFieldLabel.Text = 'Poussée totale';
            app.Thrust_tot = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.Thrust_tot.ValueDisplayFormat = '%.2f';
            app.Thrust_tot.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.TSCFEditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.TSCFEditFieldLabel.Text = 'TSCF';
            app.TSFC = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.TSFC.ValueDisplayFormat = '%.3f';
            app.TSFC.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.TxdeMonteEditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.TxdeMonteEditFieldLabel.Text = 'Tx de Montée';
            app.tx_climb = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.tx_climb.ValueDisplayFormat = '%.3f';
            app.tx_climb.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.CD0EditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.CD0EditFieldLabel.Text = 'CD0';
            app.CD0 = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.CD0.ValueDisplayFormat = '%.3f';
            app.CD0.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.TempdattenteminEditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.TempdattenteminEditFieldLabel.Text = 'Temp d''attente  [min]';
            app.T_loiter = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.T_loiter.ValueDisplayFormat = '%.0f';
            app.T_loiter.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.FractiondessenceavantatterissageEditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.FractiondessenceavantatterissageEditFieldLabel.Text = 'Fraction d''essence avant atterissage';
            app.Ffuel_res = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.Ffuel_res.ValueDisplayFormat = '%.3f';
            app.Ffuel_res.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.FractiondessencedanslesconduitesEditFieldLabel = uilabel(app.ChoixDesignerGnralTab);
            app.FractiondessencedanslesconduitesEditFieldLabel.Text = 'Fraction d''essence dans les conduites';
            app.Ffuel_trap = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.Ffuel_trap.ValueDisplayFormat = '%.3f';
            app.Ffuel_trap.ValueChangedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.RecalculerDesign = uibutton(app.ChoixDesignerGnralTab, 'push');
            app.RecalculerDesign.ButtonPushedFcn = createCallbackFcn(app, @RecalculerDesignButtonPushed, true);
            app.RecalculerDesign.Text = 'Recalculer';
            app.DistancedatterisageLabel = uilabel(app.ChoixDesignerGnralTab);
            app.DistancedatterisageLabel.Text = 'Distance d''atterisage';
            app.Dlanding = uieditfield(app.ChoixDesignerGnralTab, 'numeric');
            app.Dlanding.ValueDisplayFormat = '%.2f';
            app.ChoixDesignAileTab = uitab(app.TabGroup);
            app.ChoixDesignAileTab.Title = 'Choix Design - Aile';
            app.AngledeflcheLeradEditFieldLabel = uilabel(app.ChoixDesignAileTab);
            app.AngledeflcheLeradEditFieldLabel.Text = 'Angle de flèche Le [rad]';
            app.Angle_fleche_Le = uieditfield(app.ChoixDesignAileTab, 'numeric');
            app.Angle_fleche_Le.ValueDisplayFormat = '%.3f';
            app.Angle_fleche_Le.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.EffilementLabel = uilabel(app.ChoixDesignAileTab);
            app.EffilementLabel.Text = 'Effilement';
            app.Effilement = uieditfield(app.ChoixDesignAileTab, 'numeric');
            app.Effilement.ValueDisplayFormat = '%.3f';
            app.Effilement.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.QLabel = uilabel(app.ChoixDesignAileTab);
            app.QLabel.Text = 'Q';
            app.Q = uieditfield(app.ChoixDesignAileTab, 'numeric');
            app.Q.ValueDisplayFormat = '%.2f';
            app.Q.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.ProfildelaileEditFieldLabel = uilabel(app.ChoixDesignAileTab);
            app.ProfildelaileEditFieldLabel.Text = 'Profil de l''aile';
            app.PolaireAile = uieditfield(app.ChoixDesignAileTab, 'text');
            app.PolaireAile.ValueChangingFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.PolaireAile.Value = 'xf-naca4412-il-1000000-n5.txt';
            app.tcmaxLabel = uilabel(app.ChoixDesignAileTab);
            app.tcmaxLabel.Text = 't/c max';
            app.epmax = uieditfield(app.ChoixDesignAileTab, 'numeric');
            app.epmax.ValueDisplayFormat = '%.2f';
            app.epmax.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.xcmaxLabel = uilabel(app.ChoixDesignAileTab);
            app.xcmaxLabel.Text = 'x/c max';
            app.xcm = uieditfield(app.ChoixDesignAileTab, 'numeric');
            app.xcm.ValueDisplayFormat = '%.2f';
            app.xcm.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.AngledeflchetcmaxradLabel = uilabel(app.ChoixDesignAileTab);
            app.AngledeflchetcmaxradLabel.Text = 'Angle de flèche t/c max [rad]';
            app.Angle_fleche_epmax = uieditfield(app.ChoixDesignAileTab, 'numeric');
            app.Angle_fleche_epmax.ValueDisplayFormat = '%.3f';
            app.Angle_fleche_epmax.ValueChangedFcn = createCallbackFcn(app, @AileDesignChanged, true);
            app.ChoixDesignFuselageTab = uitab(app.TabGroup);
            app.ChoixDesignFuselageTab.Title = 'Choix Design - Fuselage';
            app.LongueurdufuselageftEditFieldLabel = uilabel(app.ChoixDesignFuselageTab);
            app.LongueurdufuselageftEditFieldLabel.Text = 'Longueur du fuselage (ft) ';
            app.l = uieditfield(app.ChoixDesignFuselageTab, 'numeric');
            app.l.ValueDisplayFormat = '%.2f';
            app.l.ValueChangedFcn = createCallbackFcn(app, @FuselageDesignChanged, true);
            app.GrandaxeEllipseftEditFieldLabel = uilabel(app.ChoixDesignFuselageTab);
            app.GrandaxeEllipseftEditFieldLabel.Text = 'Grand axe (Ellipse) (ft) ';
            app.Aside = uieditfield(app.ChoixDesignFuselageTab, 'numeric');
            app.Aside.ValueDisplayFormat = '%.2f';
            app.Aside.ValueChangedFcn = createCallbackFcn(app, @FuselageDesignChanged, true);
            app.PetitaxeEllipseftEditFieldLabel = uilabel(app.ChoixDesignFuselageTab);
            app.PetitaxeEllipseftEditFieldLabel.Text = 'Petit axe (Ellipse) (ft) ';
            app.Atop = uieditfield(app.ChoixDesignFuselageTab, 'numeric');
            app.Atop.ValueDisplayFormat = '%.2f';
            app.Atop.ValueChangedFcn = createCallbackFcn(app, @FuselageDesignChanged, true);
            app.kEtatdesurfaceEditFieldLabel = uilabel(app.ChoixDesignFuselageTab);
            app.kEtatdesurfaceEditFieldLabel.Text = 'k - Etat de surface';
            app.k_surface = uieditfield(app.ChoixDesignFuselageTab, 'numeric');
            app.k_surface.ValueDisplayFormat = '%e';
            app.k_surface.ValueChangedFcn = createCallbackFcn(app, @FuselageDesignChanged, true);
            app.QfuselageLabel = uilabel(app.ChoixDesignFuselageTab);
            app.QfuselageLabel.Text = 'Q fuselage';
            app.Q_fuselage = uieditfield(app.ChoixDesignFuselageTab, 'numeric');
            app.Q_fuselage.ValueDisplayFormat = '%.2f';
            app.Q_fuselage.ValueChangedFcn = createCallbackFcn(app, @FuselageDesignChanged, true);
            app.TabGroup2 = uitabgroup(app.DesigndaronefUIFigure);
            app.PoidsTab = uitab(app.TabGroup2);
            app.PoidsTab.Title = 'Poids';
            app.PoidsVideLabel = uilabel(app.PoidsTab);
            app.PoidsVideLabel.Text = 'Poids à Vide';
            app.Wempty = uieditfield(app.PoidsTab, 'numeric');
            app.Wempty.ValueDisplayFormat = '%.2f';
            app.Wempty.Editable = 'off';
            app.PoidsdessenceLabel = uilabel(app.PoidsTab);
            app.PoidsdessenceLabel.Text = 'Poids d''essence';
            app.Wfuel = uieditfield(app.PoidsTab, 'numeric');
            app.Wfuel.ValueDisplayFormat = '%.2f';
            app.Wfuel.Editable = 'off';
            app.PoidsaudcollageLabel = uilabel(app.PoidsTab);
            app.PoidsaudcollageLabel.Text = 'Poids au décollage';
            app.Wto = uieditfield(app.PoidsTab, 'numeric');
            app.Wto.ValueDisplayFormat = '%.2f';
            app.Wto.Editable = 'off';
            app.PoidslatterisageLabel = uilabel(app.PoidsTab);
            app.PoidslatterisageLabel.Text = 'Poids à l''atterisage';
            app.Wlanding = uieditfield(app.PoidsTab, 'numeric');
            app.Wlanding.ValueDisplayFormat = '%.2f';
            app.Wlanding.Editable = 'off';
            app.ChargelairTab = uitab(app.TabGroup2);
            app.ChargelairTab.Title = 'Charge à l''air';
            app.WSdcollageEditFieldLabel = uilabel(app.ChargelairTab);
            app.WSdcollageEditFieldLabel.Text = 'W/S décollage';
            app.WS_TO = uieditfield(app.ChargelairTab, 'numeric');
            app.WS_TO.ValueDisplayFormat = '%.2f';
            app.WS_TO.Editable = 'off';
            app.WSMonteEditFieldLabel = uilabel(app.ChargelairTab);
            app.WSMonteEditFieldLabel.Text = 'W/S Montée';
            app.WS_climb = uieditfield(app.ChargelairTab, 'numeric');
            app.WS_climb.ValueDisplayFormat = '%.2f';
            app.WS_climb.Editable = 'off';
            app.WSCroissireEditFieldLabel = uilabel(app.ChargelairTab);
            app.WSCroissireEditFieldLabel.Text = 'W/S Croissière';
            app.WS_cruise = uieditfield(app.ChargelairTab, 'numeric');
            app.WS_cruise.ValueDisplayFormat = '%.2f';
            app.WS_cruise.Editable = 'off';
            app.WSLandingEditFieldLabel = uilabel(app.ChargelairTab);
            app.WSLandingEditFieldLabel.Text = 'W/S Landing';
            app.WS_landing = uieditfield(app.ChargelairTab, 'numeric');
            app.WS_landing.ValueDisplayFormat = '%.2f';
            app.WS_landing.Editable = 'off';
            app.PousseTab = uitab(app.TabGroup2);
            app.PousseTab.Title = 'Poussée';
            app.PoussencessaireaudcollageEditFieldLabel = uilabel(app.PousseTab);
            app.PoussencessaireaudcollageEditFieldLabel.Text = 'Poussée nécessaire au décollage';
            app.T_to = uieditfield(app.PousseTab, 'numeric');
            app.T_to.ValueDisplayFormat = '%.2f';
            app.T_to.Editable = 'off';
            app.TWaudcollageLabel = uilabel(app.PousseTab);
            app.TWaudcollageLabel.Text = 'T/W au décollage';
            app.TW_to = uieditfield(app.PousseTab, 'numeric');
            app.TW_to.ValueDisplayFormat = '%.3f';
            app.TW_to.Editable = 'off';
            app.AileTab = uitab(app.TabGroup2);
            app.AileTab.Title = 'Aile';
            app.TabGroup3 = uitabgroup(app.AileTab);
            app.GometrieTab = uitab(app.TabGroup3);
            app.GometrieTab.Title = 'Géometrie';
            app.Surfaceft2EditFieldLabel = uilabel(app.GometrieTab);
            app.Surfaceft2EditFieldLabel.Text = 'Surface [ft^2]';
            app.S = uieditfield(app.GometrieTab, 'numeric');
            app.S.ValueDisplayFormat = '%.2f';
            app.S.Editable = 'off';
            app.EnvergureftLabel = uilabel(app.GometrieTab);
            app.EnvergureftLabel.Text = 'Envergure [ft]';
            app.b = uieditfield(app.GometrieTab, 'numeric');
            app.b.ValueDisplayFormat = '%.2f';
            app.b.Editable = 'off';
            app.CrftLabel = uilabel(app.GometrieTab);
            app.CrftLabel.Text = 'Cr [ft]';
            app.Cr = uieditfield(app.GometrieTab, 'numeric');
            app.Cr.ValueDisplayFormat = '%.2f';
            app.Cr.Editable = 'off';
            app.CtftLabel = uilabel(app.GometrieTab);
            app.CtftLabel.Text = 'Ct[ft]';
            app.Ct = uieditfield(app.GometrieTab, 'numeric');
            app.Ct.ValueDisplayFormat = '%.2f';
            app.Ct.Editable = 'off';
            app.CmftLabel = uilabel(app.GometrieTab);
            app.CmftLabel.Text = 'Cm [ft]';
            app.Cmoy = uieditfield(app.GometrieTab, 'numeric');
            app.Cmoy.ValueDisplayFormat = '%.2f';
            app.Cmoy.Editable = 'off';
            app.AngledeflcheC4radLabel = uilabel(app.GometrieTab);
            app.AngledeflcheC4radLabel.Text = 'Angle de flèche C/4 [rad]';
            app.Angle_fleche_c4 = uieditfield(app.GometrieTab, 'numeric');
            app.Angle_fleche_c4.ValueDisplayFormat = '%.2f';
            app.Angle_fleche_c4.Editable = 'off';
            app.AngledeflcheC2radLabel = uilabel(app.GometrieTab);
            app.AngledeflcheC2radLabel.Text = 'Angle de flèche C/2 [rad]';
            app.Angle_fleche_c2 = uieditfield(app.GometrieTab, 'numeric');
            app.Angle_fleche_c2.ValueDisplayFormat = '%.2f';
            app.Angle_fleche_c2.Editable = 'off';
            app.AerodynamismeTab = uitab(app.TabGroup3);
            app.AerodynamismeTab.Title = 'Aerodynamisme';
            app.UIAxes = uiaxes(app.AerodynamismeTab);
            title(app.UIAxes, 'Portance')
            xlabel(app.UIAxes, 'Angle d''Attaque')
            ylabel(app.UIAxes, 'Portance')
            app.AngledeCalageradEditFieldLabel = uilabel(app.AerodynamismeTab);
            app.AngledeCalageradEditFieldLabel.Text = 'Angle de Calage [rad]';
            app.angle_calage_aile = uieditfield(app.AerodynamismeTab, 'numeric');
            app.angle_calage_aile.ValueDisplayFormat = '%.3f';
            app.angle_calage_aile.Editable = 'off';
            app.acoeffdiraileEditFieldLabel = uilabel(app.AerodynamismeTab);
            app.acoeffdiraileEditFieldLabel.Text = 'a (coeff dir aile)';
            app.a_aile = uieditfield(app.AerodynamismeTab, 'numeric');
            app.a_aile.ValueDisplayFormat = '%.3f';
            app.a_aile.Editable = 'off';
            app.a0coeffdirprofilEditFieldLabel = uilabel(app.AerodynamismeTab);
            app.a0coeffdirprofilEditFieldLabel.Text = 'a0 (coeff dir profil)';
            app.a0_aile = uieditfield(app.AerodynamismeTab, 'numeric');
            app.a0_aile.ValueDisplayFormat = '%.3f';
            app.a0_aile.Editable = 'off';
            app.CLdecroisireLabel = uilabel(app.AerodynamismeTab);
            app.CLdecroisireLabel.Text = 'CL de croisière';
            app.CL_cruise = uieditfield(app.AerodynamismeTab, 'numeric');
            app.CL_cruise.ValueDisplayFormat = '%.3f';
            app.CL_cruise.Editable = 'off';
            app.ReynoldsLabel = uilabel(app.AerodynamismeTab);
            app.ReynoldsLabel.Text = 'Reynolds';
            app.Re_aile = uieditfield(app.AerodynamismeTab, 'numeric');
            app.Re_aile.ValueDisplayFormat = '%7.0f';
            app.Re_aile.Editable = 'off';
            app.CD0Label = uilabel(app.AerodynamismeTab);
            app.CD0Label.Text = 'CD0';
            app.CD0_aile = uieditfield(app.AerodynamismeTab, 'numeric');
            app.CD0_aile.ValueDisplayFormat = '%.3d';
            app.CD0_aile.Editable = 'off';
            app.FuselageTab = uitab(app.TabGroup2);
            app.FuselageTab.Title = 'Fuselage';
            app.ReynoldsEditFieldLabel = uilabel(app.FuselageTab);
            app.ReynoldsEditFieldLabel.Text = 'Reynolds';
            app.Re_fuslage = uieditfield(app.FuselageTab, 'numeric');
            app.Re_fuslage.ValueDisplayFormat = '%7.0f';
            app.Re_fuslage.Editable = 'off';
            app.CD0Label_2 = uilabel(app.FuselageTab);
            app.CD0Label_2.Text = 'CD0';
            app.CD0_fuslage = uieditfield(app.FuselageTab, 'numeric');
            app.CD0_fuslage.ValueDisplayFormat = '%.3d';
            app.CD0_fuslage.Editable = 'off';
            app.EmpenageTab = uitab(app.TabGroup2);
            app.EmpenageTab.Title = 'Empenage';
            app.PerformanceTab = uitab(app.TabGroup2);
            app.PerformanceTab.Title = 'Performance';
            app.FinessemaxLabel = uilabel(app.PerformanceTab);
            app.FinessemaxLabel.Text = 'Finesse max';
            app.Finesse = uieditfield(app.PerformanceTab, 'numeric');
            app.Finesse.ValueDisplayFormat = '%.2f';
            app.Finesse.Editable = 'off';
            app.MachRelLabel = uilabel(app.PerformanceTab);
            app.MachRelLabel.Text = 'Mach Réel';
            app.M_real = uieditfield(app.PerformanceTab, 'numeric');
            app.M_real.ValueDisplayFormat = '%.2f';
            app.M_real.Editable = 'off';
            app.AngledeMontradLabel = uilabel(app.PerformanceTab);
            app.AngledeMontradLabel.Text = 'Angle de Monté [rad]';
            app.angle_climb = uieditfield(app.PerformanceTab, 'numeric');
            app.angle_climb.ValueDisplayFormat = '%.2f';
            app.angle_climb.Editable = 'off';
            app.CLmaxLandingLabel = uilabel(app.PerformanceTab);
            app.CLmaxLandingLabel.Text = 'CL max (Landing)';
            app.CL_max_landing = uieditfield(app.PerformanceTab, 'numeric');
            app.CL_max_landing.ValueDisplayFormat = '%.2f';
            app.CL_max_landing.Editable = 'off';
            app.CLmaxTakeoffLabel = uilabel(app.PerformanceTab);
            app.CLmaxTakeoffLabel.Text = 'CL max (Takeoff)';
            app.CL_max_TO = uieditfield(app.PerformanceTab, 'numeric');
            app.CL_max_TO.ValueDisplayFormat = '%.2f';
            app.CL_max_TO.Editable = 'off';
            app.DistancedeDcollageftLabel = uilabel(app.PerformanceTab);
            app.DistancedeDcollageftLabel.Text = 'Distance de Décollage [ft]';
            app.DTO = uieditfield(app.PerformanceTab, 'numeric');
            app.DTO.ValueDisplayFormat = '%.2f';
            app.DTO.Editable = 'off';
            app.VitessededcrochageftsLabel = uilabel(app.PerformanceTab);
            app.VitessededcrochageftsLabel.Text = 'Vitesse de décrochage [ft/s]';
            app.Vstall = uieditfield(app.PerformanceTab, 'numeric');
            app.Vstall.ValueDisplayFormat = '%.2f';
            app.Vstall.Editable = 'off';
            app.VitessededcollageftsLabel = uilabel(app.PerformanceTab);
            app.VitessededcollageftsLabel.Text = 'Vitesse de décollage [ft/s]';
            app.VTo = uieditfield(app.PerformanceTab, 'numeric');
            app.VTo.ValueDisplayFormat = '%.2f';
            app.VTo.Editable = 'off';
            app.TempratureKLabel = uilabel(app.PerformanceTab);
            app.TempratureKLabel.Text = 'Température [K]';
            app.T = uieditfield(app.PerformanceTab, 'numeric');
            app.T.ValueDisplayFormat = '%.2f';
            app.T.Editable = 'off';
            app.VitessedeCroisireftsLabel = uilabel(app.PerformanceTab);
            app.VitessedeCroisireftsLabel.Text = 'Vitesse de Croisière [ft/s]';
            app.V_cruise = uieditfield(app.PerformanceTab, 'numeric');
            app.V_cruise.ValueDisplayFormat = '%.2f';
            app.V_cruise.Editable = 'off';
            app.RsultatsdeCalculLabel = uilabel(app.DesigndaronefUIFigure);
            app.RsultatsdeCalculLabel.Text = 'Résultats de Calcul';
            app.ParamtresUtilisateurLabel = uilabel(app.DesigndaronefUIFigure);
            app.ParamtresUtilisateurLabel.Text = 'Paramètres Utilisateur';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DesignAeronef

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DesigndaronefUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DesigndaronefUIFigure)
        end
    end
end